classdef Skynet_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DrinksMenuButtonGroup         matlab.ui.container.ButtonGroup
        SpriteButton_2                matlab.ui.control.Button
        CokeButton                    matlab.ui.control.Button
        VodkaButton                   matlab.ui.control.Button
        RumButton                     matlab.ui.control.Button
        ToDoImplementSafetyCiircuitLabel  matlab.ui.control.Label
        ResumeButton                  matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        SensorInputExampleButton      matlab.ui.control.Button
        VisualServoingButton          matlab.ui.control.Button
        KUKACollisionDetectionButton  matlab.ui.control.Button
        EStopToggleSwitchLabel        matlab.ui.control.Label
        KUKAJoystickControlButton     matlab.ui.control.StateButton
        UR3JoystickControlButton      matlab.ui.control.StateButton
        ObjectinWorkspaceButton       matlab.ui.control.Button
        Joint1EditFieldLabel          matlab.ui.control.Label
        Joint1EditField               matlab.ui.control.NumericEditField
        Joint2EditFieldLabel          matlab.ui.control.Label
        Joint2EditField               matlab.ui.control.NumericEditField
        Joint3EditFieldLabel          matlab.ui.control.Label
        Joint3EditField               matlab.ui.control.NumericEditField
        Joint4EditFieldLabel          matlab.ui.control.Label
        Joint4EditField               matlab.ui.control.NumericEditField
        Joint5EditFieldLabel          matlab.ui.control.Label
        Joint5EditField               matlab.ui.control.NumericEditField
        Joint6EditFieldLabel          matlab.ui.control.Label
        Joint6EditField               matlab.ui.control.NumericEditField
        Joint1EditField_2Label        matlab.ui.control.Label
        Joint1EditField_2             matlab.ui.control.NumericEditField
        Joint2EditField_2Label        matlab.ui.control.Label
        Joint2EditField_2             matlab.ui.control.NumericEditField
        Joint3EditField_2Label        matlab.ui.control.Label
        Joint3EditField_2             matlab.ui.control.NumericEditField
        Joint4EditField_2Label        matlab.ui.control.Label
        Joint4EditField_2             matlab.ui.control.NumericEditField
        Joint5EditField_2Label        matlab.ui.control.Label
        Joint5EditField_2             matlab.ui.control.NumericEditField
        Joint6EditField_2Label        matlab.ui.control.Label
        Joint6EditField_2             matlab.ui.control.NumericEditField
        SwitchLabel                   matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        EStopButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
      eStopValue = true; % Description
    end

    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VodkaButton
        function VodkaButtonPushed(app, event)
            
            ur3 = evalin('base', 'ur3');
            kuka = evalin('base', 'kuka');
            Vodka_h = evalin('base', 'Vodka_h');
            vertices = evalin('base', 'vertices');
            BartransformedVertices = evalin('base', 'BartransformedVertices');
            BenchtransformedVertices = evalin('base', 'BenchtransformedVertices');
            transformedVertices = evalin('base', 'transformedVertices');
            Vodka(ur3,kuka,Vodka_h,vertices,transformedVertices,BartransformedVertices,BenchtransformedVertices);
         
        end

        % Button pushed function: RumButton
        function RumButtonPushed(app, event)
            ur3 = evalin('base', 'ur3');
            kuka = evalin('base', 'kuka');
            Rum_h = evalin('base', 'Rum_h');
            vertices = evalin('base', 'vertices');
            BartransformedVertices = evalin('base', 'BartransformedVertices');
            BenchtransformedVertices = evalin('base', 'BenchtransformedVertices');
            transformedVertices = evalin('base', 'transformedVertices');
            Rum(ur3,kuka,Rum_h,vertices,transformedVertices,BartransformedVertices,BenchtransformedVertices);
        end

        % Button pushed function: CokeButton
        function CokeButtonPushed(app, event)
            ur3 = evalin('base', 'ur3');
            kuka = evalin('base', 'kuka');
            Coke_h = evalin('base', 'Coke_h');
            vertices = evalin('base', 'vertices');
            BartransformedVertices = evalin('base', 'BartransformedVertices');
            BenchtransformedVertices = evalin('base', 'BenchtransformedVertices');
            transformedVertices = evalin('base', 'transformedVertices');
            Coke(ur3,kuka,Coke_h,vertices,transformedVertices,BartransformedVertices,BenchtransformedVertices);
        end

        % Button pushed function: SpriteButton_2
        function SpriteButton_2Pushed(app, event)
            ur3 = evalin('base', 'ur3');
            kuka = evalin('base', 'kuka');
            Sprite_h = evalin('base', 'Sprite_h');
            vertices = evalin('base', 'vertices');
            BartransformedVertices = evalin('base', 'BartransformedVertices');
            BenchtransformedVertices = evalin('base', 'BenchtransformedVertices');
            transformedVertices = evalin('base', 'transformedVertices');
            Sprite(ur3,kuka,Sprite_h,vertices,transformedVertices,BartransformedVertices,BenchtransformedVertices);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clf
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
          hold on
          run('IRAssignment2');
      
        end

        % Button pushed function: KUKACollisionDetectionButton
        function KukaCollisionDetection(app, event)
            kuka = evalin('base', 'kuka');
            BartransformedVertices = evalin('base', 'BartransformedVertices');
            KukaCollisionDetectionExample(kuka,BartransformedVertices);
        end

        % Value changed function: KUKAJoystickControlButton
        function KUKAJoystickControlButtonValueChanged(app, event)
            value = app.KUKAJoystickControlButton.Value;
            kuka = evalin('base', 'kuka');
            KUKAcontroller(kuka);
        end

        % Value changed function: UR3JoystickControlButton
        function UR3JoystickControlButtonValueChanged(app, event)
            value = app.UR3JoystickControlButton.Value;
            ur3 = evalin('base', 'ur3');
            UR3controller(ur3);
        end

        % Button pushed function: ObjectinWorkspaceButton
        function ObjectInWorkspace(app, event)
            v = evalin('base', 'v');
            f = evalin('base', 'f');
            fn = evalin('base', 'fn');
            hand = evalin('base', 'hand');
            ObjectInWorkspace(hand,v,f,fn)
            uiwait();
            
        end

        % Button pushed function: ResumeButton
        function Resume(app, event)
            hand = evalin('base', 'hand');
            ObjectOutOfWorkspace(hand)
            uiresume();
        end

        % Value changed function: Joint1EditField
        function Joint1EditFieldValueChanged(app, event)
            kuka = evalin('base', 'kuka');
           while app.KUKAJoystickControlButton.Value(0)
            q = kuka.model.getpos;
            value = q(1);
            app.Joint1EditField.Value = value;
            drawnow;
           end
        end

        % Button pushed function: EStopButton
        function EStop(app, event)
              if app.eStopValue == true
                uiwait();
            else
                uiresume(); 
                app.Switch.Value = 'Off';
            end
              
            
        end

        % Value changed function: Switch
        function ToggleEstop(app, event)
         
            value = app.Switch.Value;
            
            if strcmp(value,'Off')
                app.eStopValue = true;
            else
                app.eStopValue = false;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DrinksMenuButtonGroup = uibuttongroup(app.UIFigure);
            app.DrinksMenuButtonGroup.Title = 'Drinks Menu';
            app.SpriteButton_2 = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.SpriteButton_2.ButtonPushedFcn = createCallbackFcn(app, @SpriteButton_2Pushed, true);
            app.SpriteButton_2.Text = {'Sprite'; ''};
            app.CokeButton = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.CokeButton.ButtonPushedFcn = createCallbackFcn(app, @CokeButtonPushed, true);
            app.CokeButton.Text = 'Coke';
            app.VodkaButton = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.VodkaButton.ButtonPushedFcn = createCallbackFcn(app, @VodkaButtonPushed, true);
            app.VodkaButton.Text = 'Vodka';
            app.RumButton = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.RumButton.ButtonPushedFcn = createCallbackFcn(app, @RumButtonPushed, true);
            app.RumButton.Text = {'Rum'; ''};
            app.ToDoImplementSafetyCiircuitLabel = uilabel(app.UIFigure);
            app.ToDoImplementSafetyCiircuitLabel.Text = {'To Do - '; '- Look at implementing sensors'; 'and tracking output to a screen'; '-Use those sensors to trip or reset'; 'safery circuit and estop'};
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @Resume, true);
            app.ResumeButton.Text = {'Resume'; ''};
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SensorInputExampleButton = uibutton(app.UIFigure, 'push');
            app.SensorInputExampleButton.Text = 'Sensor Input Example';
            app.VisualServoingButton = uibutton(app.UIFigure, 'push');
            app.VisualServoingButton.Text = 'Visual Servoing';
            app.KUKACollisionDetectionButton = uibutton(app.UIFigure, 'push');
            app.KUKACollisionDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @KukaCollisionDetection, true);
            app.KUKACollisionDetectionButton.Text = {'KUKA Collision '; 'Detection '; ''};
            app.EStopToggleSwitchLabel = uilabel(app.UIFigure);
            app.EStopToggleSwitchLabel.Text = 'E-Stop Toggle Switch';
            app.KUKAJoystickControlButton = uibutton(app.UIFigure, 'state');
            app.KUKAJoystickControlButton.ValueChangedFcn = createCallbackFcn(app, @KUKAJoystickControlButtonValueChanged, true);
            app.KUKAJoystickControlButton.Text = 'KUKA Joystick Control';
            app.UR3JoystickControlButton = uibutton(app.UIFigure, 'state');
            app.UR3JoystickControlButton.ValueChangedFcn = createCallbackFcn(app, @UR3JoystickControlButtonValueChanged, true);
            app.UR3JoystickControlButton.Text = 'UR3 Joystick Control';
            app.ObjectinWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ObjectinWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectInWorkspace, true);
            app.ObjectinWorkspaceButton.Text = {'Object in Workspace'; ''};
            app.Joint1EditFieldLabel = uilabel(app.UIFigure);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint1EditField.ValueDisplayFormat = '%g';
            app.Joint1EditField.ValueChangedFcn = createCallbackFcn(app, @Joint1EditFieldValueChanged, true);
            app.Joint1EditField.Editable = 'off';
            app.Joint2EditFieldLabel = uilabel(app.UIFigure);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.UIFigure);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint4EditFieldLabel = uilabel(app.UIFigure);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.Joint4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint5EditFieldLabel = uilabel(app.UIFigure);
            app.Joint5EditFieldLabel.Text = 'Joint 5';
            app.Joint5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint6EditFieldLabel = uilabel(app.UIFigure);
            app.Joint6EditFieldLabel.Text = 'Joint 6';
            app.Joint6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint1EditField_2Label = uilabel(app.UIFigure);
            app.Joint1EditField_2Label.Text = 'Joint 1';
            app.Joint1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Joint2EditField_2Label = uilabel(app.UIFigure);
            app.Joint2EditField_2Label.Text = 'Joint 2';
            app.Joint2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Joint3EditField_2Label = uilabel(app.UIFigure);
            app.Joint3EditField_2Label.Text = 'Joint 3';
            app.Joint3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Joint4EditField_2Label = uilabel(app.UIFigure);
            app.Joint4EditField_2Label.Text = 'Joint 4';
            app.Joint4EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Joint5EditField_2Label = uilabel(app.UIFigure);
            app.Joint5EditField_2Label.Text = 'Joint 5';
            app.Joint5EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Joint6EditField_2Label = uilabel(app.UIFigure);
            app.Joint6EditField_2Label.Text = 'Joint 6';
            app.Joint6EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @ToggleEstop, true);
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStop, true);
            app.EStopButton.Text = 'E-Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Skynet_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end